classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        animateButton         matlab.ui.control.Button
        loadcircleButton      matlab.ui.control.Button
        radiusSlider          matlab.ui.control.Slider
        radiusSliderLabel     matlab.ui.control.Label
        latitudeSlider        matlab.ui.control.Slider
        latitudeSliderLabel   matlab.ui.control.Label
        longitudeSlider       matlab.ui.control.Slider
        longitudeSliderLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        latitude = 90;
        longitude = 180;
        radius = 77.5;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: loadcircleButton
        function loadcircleButtonPushed(app, event)
          close all;
          %creates graph
          figure;
          hold on;
          axis([-2, 2, -2, 2, -2, 2]);
          grid on;
          view(3);
          axis vis3d;
          rotate3d on;
          xlabel('X'); ylabel('Y'); zlabel('Z');
          title('IRB140 Circle Trace')
          %----------------------------------
          nPoints = 100;
          %SETS up sphere in space
          sRadius = .4; % sphere radius
          xOffset = 1;
          yOffset = 0;
          zOffset = 0.25;
          [X, Y, Z] = sphere(nPoints);
          X = sRadius * X + xOffset;
          Y = sRadius * Y + yOffset;
          Z = sRadius * Z + zOffset;
          %plots sphere
          surf(X, Y, Z, 'EdgeColor','none');
          %----------------------------------
          %sets up the circle on the sphere
          %parameters of circle in degs bc long and lat units are deg
          xdeg = app.longitude; %longitude in degs 145<x<215
          ydeg = app.latitude; %latitude in degs 60<x<120
          rdeg = app.radius; %radius in degs (105 and 75 deg works best 90 does not work)
          %make degs to rads
          xrad = deg2rad(xdeg);
          yrad = deg2rad(ydeg);
          rrad = deg2rad(rdeg);
          %find center of circle
          cCoords = zeros(3, nPoints);
          theta = linspace(0, 2*pi, nPoints);
          %creates circle
          for i = 1:nPoints
            cCoords(1, i) = cos(rrad) * cos(theta(i)); % X
            cCoords(2, i) = cos(rrad) * sin(theta(i)); % Y
            cCoords(3, i) = sin(rrad) * ones(size(theta(i))); % Z
          end
          %rotation matrix
          Rcircle = [cos(xrad), -sin(xrad), 0; sin(xrad), cos(xrad), 0; 0, 0, 1] * ...
            [cos(yrad), 0, sin(yrad); 0, 1, 0; -sin(yrad), 0, cos(yrad)];
          cCoords = Rcircle * cCoords;
          % %plots circle
          plot3(cCoords(1, :)*sRadius + xOffset, ...
              cCoords(2, :)*sRadius + yOffset, ...
              cCoords(3, :)*sRadius + zOffset, ...
              'r-', 'LineWidth', 2);
        end

        % Button pushed function: animateButton
        function animateButtonPushed(app, event)
          close all;
          %theta d a alpha
          L1 = Link([0, .352, 0.07, -pi/2]);
          L2 = Link([0, 0, .36, 0]);
          L3 = Link([0, 0, 0, pi/2]);
          L4 = Link([0, .380, 0, -pi/2]);
          L5 = Link([0, 0, 0, pi/2]);
          L6 = Link([0, .065, 0, 0]);
          robot = SerialLink([L1 L2 L3 L4 L5 L6], 'name', 'IRB140');
          %define joint limits (from ABB Library)
          robot.qlim = [
            deg2rad([-360, 360]);
            deg2rad([-200, 200]);
            deg2rad([-280, 280]);
            deg2rad([-400, 400]);
            deg2rad([-240, 240]);
            deg2rad([-800, 800])
          ];
          %creates graph
          figure;
          hold on;
          axis([-2, 2, -2, 2, -2, 2]);
          grid on;
          view(3);
          axis vis3d;
          rotate3d on;
          xlabel('X'); ylabel('Y'); zlabel('Z');
          title('IRB140 Circle Trace')
          %----------------------------------
          nPoints = 100;
          %SETS up sphere in space
          sRadius = .4; % sphere radius
          xOffset = 1;
          yOffset = 0;
          zOffset = 0.25;
          [X, Y, Z] = sphere(nPoints);
          X = sRadius * X + xOffset;
          Y = sRadius * Y + yOffset;
          Z = sRadius * Z + zOffset;
          %plots sphere
          surf(X, Y, Z, 'EdgeColor','none');
          %----------------------------------
          %sets up the circle on the sphere
          %parameters of circle in degs bc long and lat units are deg
          xdeg = app.longitude; %longitude in degs 145<x<215
          ydeg = app.latitude; %latitude in degs 60<x<120
          rdeg = app.radius; %radius in degs (105 and 75 deg works best 90 does not work)
          %make degs to rads
          xrad = deg2rad(xdeg);
          yrad = deg2rad(ydeg);
          rrad = deg2rad(rdeg);
          %find center of circle
          cCoords = zeros(3, nPoints);
          theta = linspace(0, 2*pi, nPoints);
          %creates circle
          for i = 1:nPoints
            cCoords(1, i) = cos(rrad) * cos(theta(i)); % X
            cCoords(2, i) = cos(rrad) * sin(theta(i)); % Y
            cCoords(3, i) = sin(rrad) * ones(size(theta(i))); % Z
          end
          %rotation matrix
          Rcircle = [cos(xrad), -sin(xrad), 0; sin(xrad), cos(xrad), 0; 0, 0, 1] * ...
            [cos(yrad), 0, sin(yrad); 0, 1, 0; -sin(yrad), 0, cos(yrad)];
          cCoords = Rcircle * cCoords; 
          %norm of sphere & initialize array for trail
          normals = -(cCoords ./ vecnorm(cCoords));
          endpos = [];
          %guess 1 to start for ikcon
          qguess = [0, pi/2, 0, 0, 0, 0];
          %position and orientation errors
          postol = 1e-3;
          orientol = 1e-2;
          % Define trajectory
          for i = 1:nPoints
            % Position (x, y, z)
            pos = [cCoords(1, i)*sRadius+1, cCoords(2, i)*sRadius, cCoords(3, i)*sRadius+.25];
            %normal vector to the sphere at this point
            znorm = normals(:, i); %normal vector aligned with z-axis of end effector
            xnorm = cross([0; 1; 0], znorm); %choose an perp x-axis
            if norm(xnorm) == 0
              xnorm = cross([1; 0; 0], znorm); %singulatrities
            end
            xnorm = xnorm / norm(xnorm); %norm x-axis
            ynorm = cross(znorm, xnorm); %y-axis orthog to both
            %rotation matrix
            Rrobot = [xnorm ynorm znorm];
            %homogeneous transformation matrix
            T = [Rrobot, pos'; 0 0 0 1];
            %variables for trail
            endeffpos = T(1:3, 4);
            endpos = [endpos, endeffpos];
            %ik for joint angles
            q(i, :) = robot.ikcon(T, qguess);
            %check if point is in range
            Treal = robot.fkine(q(i, :)).T;
            %position and orientation errors
            poserror = norm(T(1:3, 4) - Treal(1:3, 4));
            orienerror = norm(logm(T(1:3, 1:3)' * Treal(1:3, 1:3)), 'fro');
            %check if point is out of range
            if poserror > postol || orienerror > orientol
              fprintf(['Error: Cannot reach position at point %d (Position error: %.4f, ', ...
              'Orientation error: %.4f)\n'], i, poserror, orienerror);
              break;
            end
            %update q
            qguess = q(i, :);
            %plot
            robot.plot(q(i, :));
            %plot trail
            plot3(endpos(1, :), endpos(2, :), endpos(3, :), 'r', 'LineWidth', 2);
            pause(0.025);
          end
        end

        % Value changed function: radiusSlider
        function radiusSliderValueChanged(app, event)
            app.radius = app.radiusSlider.Value;
            
        end

        % Value changed function: latitudeSlider
        function latitudeSliderValueChanged(app, event)
            app.latitude = app.latitudeSlider.Value;
            
        end

        % Value changed function: longitudeSlider
        function longitudeSliderValueChanged(app, event)
            app.longitude = app.longitudeSlider.Value;
            
        end

        % Value changing function: radiusSlider
        function radiusSliderValueChanging(app, event)
            app.radius = event.Value;
            
        end

        % Value changing function: latitudeSlider
        function latitudeSliderValueChanging(app, event)
            app.latitude = event.Value;
            
        end

        % Value changing function: longitudeSlider
        function longitudeSliderValueChanging(app, event)
            app.longitude = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.longitudeSliderLabel = uilabel(app.UIFigure);
            app.longitudeSliderLabel.Text = 'longitude';
            app.longitudeSlider = uislider(app.UIFigure);
            app.longitudeSlider.Limits = [165 195];
            app.longitudeSlider.ValueChangedFcn = createCallbackFcn(app, @longitudeSliderValueChanged, true);
            app.longitudeSlider.ValueChangingFcn = createCallbackFcn(app, @longitudeSliderValueChanging, true);
            app.longitudeSlider.Value = 180;
            app.latitudeSliderLabel = uilabel(app.UIFigure);
            app.latitudeSliderLabel.Text = 'latitude';
            app.latitudeSlider = uislider(app.UIFigure);
            app.latitudeSlider.Limits = [75 105];
            app.latitudeSlider.ValueChangedFcn = createCallbackFcn(app, @latitudeSliderValueChanged, true);
            app.latitudeSlider.ValueChangingFcn = createCallbackFcn(app, @latitudeSliderValueChanging, true);
            app.latitudeSlider.Value = 90;
            app.radiusSliderLabel = uilabel(app.UIFigure);
            app.radiusSliderLabel.Text = 'radius';
            app.radiusSlider = uislider(app.UIFigure);
            app.radiusSlider.Limits = [70 85];
            app.radiusSlider.ValueChangedFcn = createCallbackFcn(app, @radiusSliderValueChanged, true);
            app.radiusSlider.ValueChangingFcn = createCallbackFcn(app, @radiusSliderValueChanging, true);
            app.radiusSlider.Value = 77.5;
            app.loadcircleButton = uibutton(app.UIFigure, 'push');
            app.loadcircleButton.ButtonPushedFcn = createCallbackFcn(app, @loadcircleButtonPushed, true);
            app.loadcircleButton.Text = 'load circle';
            app.animateButton = uibutton(app.UIFigure, 'push');
            app.animateButton.ButtonPushedFcn = createCallbackFcn(app, @animateButtonPushed, true);
            app.animateButton.Text = 'animate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end